classdef BASSA < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        AudioPlaybackPanel              matlab.ui.container.Panel
        VolumeSlider                    matlab.ui.control.Slider
        VolumeSliderLabel               matlab.ui.control.Label
        LoopPlaySwitch                  matlab.ui.control.Switch
        LoopPlaySwitchLabel             matlab.ui.control.Label
        XLabel                          matlab.ui.control.Label
        PlaybackSpeedSpinner            matlab.ui.control.Spinner
        PlaybackSpeedSpinnerLabel       matlab.ui.control.Label
        PlayButton                      matlab.ui.control.StateButton
        AudioOutputDeviceDropDown       matlab.ui.control.DropDown
        AudioOutputDeviceDropDownLabel  matlab.ui.control.Label
        TabGroup                        matlab.ui.container.TabGroup
        PreProcessingTab                matlab.ui.container.Tab
        Panel_timesig                   matlab.ui.container.Panel
        UIAxes_freqsig                  matlab.ui.control.UIAxes
        UIAxes_timesig                  matlab.ui.control.UIAxes
        LoadFilePanel                   matlab.ui.container.Panel
        LoadAudioFileButton             matlab.ui.control.Button
        PreprocessingSettingsPanel      matlab.ui.container.Panel
        Panel                           matlab.ui.container.Panel
        Preproc_exportDropDown          matlab.ui.control.DropDown
        Preproc_ExportPlotsButton       matlab.ui.control.Button
        ResetAllButton                  matlab.ui.control.Button
        MagRespAxisScalingPanel         matlab.ui.container.Panel
        MagnitudeUnitsSwitch            matlab.ui.control.Switch
        MagnitudeUnitsSwitchLabel       matlab.ui.control.Label
        preproc_FAxisScaleSwitch        matlab.ui.control.Switch
        FrequencyAxisScaleSwitchLabel   matlab.ui.control.Label
        NormPanel                       matlab.ui.container.Panel
        preproc_NormalizeButton         matlab.ui.control.Button
        ResampPanel                     matlab.ui.container.Panel
        ResampleButton                  matlab.ui.control.Button
        UpperFreqLimitHzEditField       matlab.ui.control.NumericEditField
        UpperFreqLimitHzEditFieldLabel  matlab.ui.control.Label
        SampleRateHzEditField           matlab.ui.control.NumericEditField
        SampleRateHzEditFieldLabel      matlab.ui.control.Label
        TrimPanel                       matlab.ui.container.Panel
        EndTimesEditField               matlab.ui.control.NumericEditField
        EndTimesEditFieldLabel          matlab.ui.control.Label
        StartTimesEditField             matlab.ui.control.NumericEditField
        StartTimesEditFieldLabel        matlab.ui.control.Label
        TrimSignalButton                matlab.ui.control.Button
        SuperletScalogramTab            matlab.ui.container.Tab
        TimeFrequencyDomainSignalPlotPanel_3  matlab.ui.container.Panel
        UIAxes_SLTScalo                 matlab.ui.control.UIAxes
        Panel_2                         matlab.ui.container.Panel
        PlotSettingsPanel               matlab.ui.container.Panel
        GridLayout9                     matlab.ui.container.GridLayout
        GridLayout11                    matlab.ui.container.GridLayout
        GridLayout12                    matlab.ui.container.GridLayout
        SLT_FAxisScalingSwitch          matlab.ui.control.Switch
        FrequencyAxisScalingSwitchLabel  matlab.ui.control.Label
        SLTExportFormatDropDown         matlab.ui.control.DropDown
        SLT_ExportPlotButton            matlab.ui.control.Button
        GridLayout10                    matlab.ui.container.GridLayout
        AxisLimitsPanel                 matlab.ui.container.Panel
        HighFreqLimitHzEditField        matlab.ui.control.NumericEditField
        HighFreqLimitHzEditFieldLabel   matlab.ui.control.Label
        LowFreqLimitHzEditField         matlab.ui.control.NumericEditField
        LowFreqLimitHzEditFieldLabel    matlab.ui.control.Label
        EndTimesEditField_2             matlab.ui.control.NumericEditField
        EndTimesEditField_2Label        matlab.ui.control.Label
        StartTimesEditField_2           matlab.ui.control.NumericEditField
        StartTimesEditField_2Label      matlab.ui.control.Label
        DynamicRangearbEditField        matlab.ui.control.NumericEditField
        DynamicRangearbEditFieldLabel   matlab.ui.control.Label
        ResetAllLimitsButton            matlab.ui.control.Button
        SLT_SigQuantityUnitsButtonGroup  matlab.ui.container.ButtonGroup
        LogPowerdBWButton               matlab.ui.control.ToggleButton
        PowerWButton                    matlab.ui.control.ToggleButton
        LogMagnitudedBButton            matlab.ui.control.ToggleButton
        MagnitudearbButton              matlab.ui.control.ToggleButton
        AlgorithmSettingsPanel          matlab.ui.container.Panel
        TabGroup2                       matlab.ui.container.TabGroup
        BasicSettingsTab                matlab.ui.container.Tab
        GridLayout                      matlab.ui.container.GridLayout
        GridLayout2                     matlab.ui.container.GridLayout
        RAM_est_PERC_EditField          matlab.ui.control.NumericEditField
        RAM_est_PERC_EditFieldLabel     matlab.ui.control.Label
        MaxTimeResolutionSecondsEditField  matlab.ui.control.NumericEditField
        MaxTimeResolutionSecondsEditFieldLabel  matlab.ui.control.Label
        TimeResolutionSecondsEditField  matlab.ui.control.NumericEditField
        TimeResolutionSecondsEditFieldLabel  matlab.ui.control.Label
        FrequencyResolutionHzEditField  matlab.ui.control.NumericEditField
        FrequencyResolutionHzEditFieldLabel  matlab.ui.control.Label
        HighestFSpinner                 matlab.ui.control.Spinner
        HighestFrequencyOfInterestHzSpinnerLabel  matlab.ui.control.Label
        LowestFSpinner                  matlab.ui.control.Spinner
        LowestFrequencyOfInterestHzSpinnerLabel  matlab.ui.control.Label
        PlotScalogramButton             matlab.ui.control.Button
        AdvancedSettingsTab             matlab.ui.container.Tab
        GridLayout3                     matlab.ui.container.GridLayout
        GridLayout7                     matlab.ui.container.GridLayout
        CyclesSpinner                   matlab.ui.control.Spinner
        InitialNumberofSuperletCyclesSpinnerLabel  matlab.ui.control.Label
        Upper_order_EditField           matlab.ui.control.NumericEditField
        UpperSuperresolutionorderEditFieldLabel  matlab.ui.control.Label
        Lower_order_EditField           matlab.ui.control.NumericEditField
        LowerSuperresolutionorderEditFieldLabel  matlab.ui.control.Label
        GridLayout4                     matlab.ui.container.GridLayout
        GridLayout8                     matlab.ui.container.GridLayout
        RAM_est_EditField               matlab.ui.control.NumericEditField
        RAM_est_EditFieldLabel          matlab.ui.control.Label
        MemorySafetyLimiterSwitch       matlab.ui.control.Switch
        MemorySafetyLimiterSwitchLabel  matlab.ui.control.Label
        SuperresolutionModeSwitch       matlab.ui.control.Switch
        SuperresolutionModeSwitchLabel  matlab.ui.control.Label
    end


    % Properties that correspond to variables
    properties (Access = public)
        maxsiglen       % Maximum length of vector passed to SLT tab from pre-processing 
        maxfs           % Maximum sample rate of vector passed to SLT tab from pre-processing 
        file            % File name of the audio to load
        path            % Path to the audio to load
        signal          % Original audio signal
        working_signal  % Signal to analyse (with preprocessing modifications)
        original_fs     % The sampling frequency of the original audio signal
        working_fs      % The Fs of working_signal
        last_fs         % The previous setting of the sample rate edit box
        t_vec           % The time vector of the original signal
        working_t_vec   % The time vector for the working signal
        t_start         % Start time for trimmed audio
        t_end           % End time for trimmed audio
        t_start_idx     % The index for the start of the trimmed signal
        t_end_idx       % The index for the end of the trimmed signal
        trimmed_flag    % A flag that indicates to other functions whether the signal has been trimmed
        resampd_flag    % A flag that indicates to other functions whether the signal has been resampled
        normed_flag     % A flag that indicates to other functions whether the signal has been normalised
        fmin            % Lowest frequency of interest for SLT transform
        fmax            % Highest frequency of interest for SLT transform
        fres            % Target frequency resolution for SLT transform
        cycles          % Initial number of superlet cycles for SLT transform
        order           % Interval of superresolution orders for SLT transform
        mult            % Superresolution mode SLT transform
        SLT_units       % Sets signal quantity units for the SLT TFR plot
        SLT_fscale      % Sets the frequecny axis scaling for the SLT TFR plot
        slt             % Raw result of the superlet transform
        sltplot         % Post-Processed result of the superlet transform
        preproc_fscale  % The frequency axis scaling for the magnitude response plot
        SLT_f_vec       % The frequency axis values for the SLT TFR Plot
        colorbar_label  % Dynamic Label for colorbar in TFR plots
        preprocunit_value           % The units used in the preprocessing plots
        preproc_f_vec               % The frequency vector used in the preproc magspect plot       
        f_signal                    % The signal in the frequency domain
        f_signal_unit_converted     % The signal in the frequency domain, mag units converted 
        f_signal_norm               % The signal in the frequency domain, normalized
        closest_HW_fs               % The supported Fs that most closely matches the Fs of the selected out hardware
        play_fs                     % The Fs to play back at, taking into account playspeed control
        play_signal                 % the resampled, normed and volume adjusted signal to play
        devINFO                     % Audio hardware device info
        devID                       % Audio hardware device ID for selected device
        player                      % audioPlayer System Object created for the selected device (Contains play_signal)
        previous_gain_factor        % the previous gain factor, used to calculate gain change for next setting adjustment
        SLTunitconverted_flag       % Indicates that the SLT result has been unit converted
        t_vec_reduced               % Time vector for SLT result that has been downsampled on the time axis
        slt_ncolumns_reduced        % Copy of the SLT result that has been downsampled on the time axis
        sltsignal_duration          % Duration of the signal
        n_freqs                     % Number of frequency points evaluated in SLT
        hasEnoughMemory             % Flag that indicates whether the system has sufficient memory to plot
        slt_maxval                  % Maximum intensity value in SLT result
        slt_minval                  % Minimum intensity value in SLT result
        slt_timelim                 % X-axis (time) limits for SLT plot
        slt_freqlim                 % Y-axis (frequency) limits for SLT plot
        SLT_initialised_flag        % Flag that indicates whether the SLT screen has been initialised.
    end

    methods (Access = private)

        %%%%%%%%%%%%%%%%%% PreProcesing Tab - Redraw Time Domain Plot %%%%%%%%%%%%%%%%%%
        function redrawTimeplot(app, t, s)
            % Update plot
            plot(app.UIAxes_timesig, t, s, 'Tag', 'Timedata');

            % Set plot axis limits
            if app.normed_flag == 0
                app.UIAxes_timesig.YLim = [min(s), max(s)];
            elseif app.normed_flag == 1
                app.UIAxes_timesig.YLim = [-1.1, 1.1];
            end
            drawnow;
            figure(app.UIFigure)
        end

        %%%%%%%%%%%%%%%%%% PreProcesing Tab - Redraw Freq Domain Plot %%%%%%%%%%%%%%%%%%
        function redrawMAGplot(app, f)
            % If signal has been resampled, use new sample rate
            if app.resampd_flag == 0
                fs = app.original_fs;
            elseif app.resampd_flag == 1
                fs = app.working_fs;
            end

            % Generate frequency vector
            app.preproc_f_vec =  linspace(1, fs/2, length(f));

            % Generate plot
            plot(app.UIAxes_freqsig, app.preproc_f_vec, f)

            % Set plot x-axis limits
            app.UIAxes_freqsig.XLim = [1, fs/2];

            % Set plot y-axis limits
            if app.normed_flag == 0 && app.preprocunit_value == 0
                app.UIAxes_freqsig.YLim = [min(f), max(f)];
            elseif app.normed_flag == 0 && app.preprocunit_value == 1
                app.UIAxes_freqsig.YLim = [0, max(f)];
            elseif app.normed_flag == 1 && app.preprocunit_value == 0
                app.UIAxes_freqsig.YLim = [0, 1];
            elseif app.normed_flag == 1 && app.preprocunit_value == 1
                app.UIAxes_freqsig.YLim = [min(f), 1];
            end
            drawnow
            figure(app.UIFigure)
        end

        %%%%%%%%%%%%%%%%%% PreProcessing Tab - Init Default Values %%%%%%%%%%%%%%%%%%
        function preprocINIT(app)
            % Set fs limits and init values
            app.SampleRateHzEditField.Limits = [50, app.original_fs];
            app.SampleRateHzEditField.Value = app.original_fs;
            app.UpperFreqLimitHzEditField.Value = app.original_fs / 2;
            app.fmax = (app.original_fs/2)-1;

            % Set time limits and init values
            app.StartTimesEditField.Limits = [app.t_vec(1), app.t_vec(end-1)];
            app.StartTimesEditField.Value = app.t_vec(1);
            app.EndTimesEditField.Limits = [app.t_vec(2), app.t_vec(end)];
            app.EndTimesEditField.Value = app.t_vec(end);
            app.t_start_idx = 1;
            app.t_end_idx = length(app.signal);

            % Reset mag units to linear
            app.MagnitudeUnitsSwitch.Value = 'Linear';

            % Set logical operatr for units to 0/linear
            app.preprocunit_value = strcmp(app.MagnitudeUnitsSwitch.Value, 'Log (dB)');

            % Reset fscaling to linear
            app.preproc_FAxisScaleSwitch.Value = 'Lin';

        end

        %%%%%%%%%%%%%%%%%% PreProcessing Tab - Load Audio File %%%%%%%%%%%%%%%%%%
        function loadfile(app)
            if isprop(app, 'file') == 1 && ~isequal(app.file, 0)
                % Load the audio file
                [app.signal, app.original_fs] = audioread(fullfile(app.path, app.file));

                % Deal with multichannel files
                if size(app.signal, 2) > 1
                    prompt = {['The selected file contains more than one channel of audio.', newline,...
                        'Multichannel audio is not currently supported.', newline,...
                        'Please enter the channel number for the file you want to analyse:']};
                    dlgtitle = 'Select Channel';
                    dims = [1 70];
                    definput = {'1'};
                    selected_channel = inputdlg(prompt,dlgtitle,dims,definput);
                    app.signal = app.signal(:, str2double(selected_channel));
                else
                end

                % Run DC Filter
                app.signal = dcfilt(app, app.signal);

                % If signal has an odd number of samples, drop the last one
                if mod(length(app.signal), 2) == 0
                    % do nothing
                elseif mod(length(app.signal), 2) ~= 0
                    app.signal = app.signal(1:end-1);
                end

                % Set up time vector
                samps = length(app.signal);
                duration = samps / app.original_fs;
                app.t_vec = linspace(0, duration, samps);

                % Initialise time and fs default values
                preprocINIT(app)

                % Set flags to say that the signal has NOT yet been modified
                app.trimmed_flag = 0;
                app.resampd_flag = 0;
                app.normed_flag = 0;
                app.SLT_initialised_flag = 0;

                % Plot time domain waveform
                redrawTimeplot(app, app.t_vec, app.signal)

                % Compute Mag Spectrum
                computemagspect(app, app.signal)

                % Plot Magnitude Spectrum
                redrawMAGplot(app, app.f_signal)

                % Initialise the audio output menu
                init_audioIO(app)
            end
        end

        %%%%%%%%%%%%%%%%%% PreProcessing Tab - Trim time domain signal %%%%%%%%%%%%%%%%%%
        function trimsig(app)
            % Trim signal according to start and end time settings
            if app.trimmed_flag == 0 && app.resampd_flag == 0 && app.normed_flag == 0
                app.working_signal = app.signal(round(app.t_start_idx):round(app.t_end_idx));
            elseif app.trimmed_flag == 1 || app.resampd_flag == 1 || app.normed_flag == 1
                app.working_signal = app.working_signal(round(app.t_start_idx):round(app.t_end_idx));
            end

            % Run DC Filter
            app.working_signal = dcfilt(app, app.working_signal);

            % Build new time vector
            workingsig_samps = length(app.working_signal);
            workingsig_duration = workingsig_samps / app.original_fs;
            app.working_t_vec = linspace(0, workingsig_duration, workingsig_samps);

            % Set flag to say that the signal has been trimmed
            app.trimmed_flag = 1;

            % Disable edit start and end time fields & trim button
            app.StartTimesEditField.Editable = 'off';
            app.EndTimesEditField.Editable = "off";
            app.TrimSignalButton.Enable = "off";
        end

        %%%%%%%%%%%%%%%%%% PreProcessing Tab - Resample Signal %%%%%%%%%%%%%%%%%%
        function resamp(app)
            % Resampled signal bandwidth
            bw = floor(min([app.working_fs, app.original_fs]) * 0.48);

            if app.trimmed_flag == 0 && app.resampd_flag == 0
                app.working_signal = easySRC(app.signal, app.original_fs, app.working_fs, bw);

            elseif app.trimmed_flag == 1 && app.resampd_flag == 0
                app.working_signal = easySRC(app.working_signal, app.original_fs, app.working_fs, bw);

            elseif app.trimmed_flag == 1 && app.resampd_flag == 1
                app.working_signal = easySRC(app.working_signal, app.original_fs, app.working_fs, bw);

            elseif app.trimmed_flag == 0 && app.resampd_flag == 1
                app.working_signal = easySRC(app.signal, app.original_fs, app.working_fs, bw);

            end

            % Run DC Filter
            app.working_signal = dcfilt(app, app.working_signal);

            % Build new time vector
            samps = length(app.working_signal);
            duration = samps / app.working_fs;
            app.working_t_vec = linspace(0, duration, samps);

            % Set Flag to show that signal has been resampled
            app.resampd_flag = 1;

            % If normalised before resampling, re-enable normalisation
            % button
            if app.normed_flag == 1
                app.preproc_NormalizeButton.Enable = "on";
            end

            % Update fmax
            app.fmax = (app.working_fs / 2) -1;
        end

        %%%%%%%%%%%%%%%%%% PreProcessing Tab - Compute Magnitude Spectrum %%%%%%%%%%%%%%%%%%
        function computemagspect(app, x)
            
            % If length of x is odd, trim one sample.
            if mod(length(x),2) ~= 0
                x = x(1:end-1);
            end

            % Compute fft of signal
            nfft = length(x);
            app.f_signal = fft(x, nfft);

            % Take the absolute value and discard the negative frequencies
            app.f_signal = abs(app.f_signal(1:nfft/2+1));
        end

        %%%%%%%%%%%%%%%%%% Preprocessing Tab - Normalize %%%%%%%%%%%%%%%%%%
        function preproc_norm(app)
            % Check which signal to use as time domain input
            if app.trimmed_flag == 0 && app.resampd_flag == 0
                x = app.signal;
            elseif app.trimmed_flag == 1 || app.resampd_flag == 1
                x = app.working_signal;
            end

            % Do normalisation of time domain input
            app.working_signal = x ./ abs(max(x));

            % Do normalisation of frequency domain input
            app.f_signal_norm = app.f_signal ./ max(app.f_signal);

            % Set flag to indicate signal has been normed
            app.normed_flag = 1;
        end

        %%%%%%%%%%%%%%%%%% Preprocessing Tab - Update Mag plot Frequency Axis %%%%%%%%%%%%%%%%%%
        function preprocfaxisswitch(app, value)
            % Convert value to type: logical: 1 for Lin, 0 for log.
            value = strcmp(value, 'Lin');

            % Render updated plot
            if value == 1 % linear
                app.UIAxes_freqsig.XScale = 'linear';
                app.UIAxes_freqsig.XLim = [1, app.fmax];
            elseif value == 0 % Log
                app.UIAxes_freqsig.XScale = 'log';
                app.UIAxes_freqsig.XLim = [1, app.fmax];
            end
        end

        %%%%%%%%%%%%%%%%%% PreProcessing Tab - Update Magnitude units %%%%%%%%%%%%%%%%%%
        function preproc_mag_unitupdate(app)
            if app.normed_flag == 0
                x = app.f_signal;
            elseif app.normed_flag == 1
                x = app.f_signal_norm;
            end

            % Unit conversion
            if app.preprocunit_value == 1
                app.f_signal_unit_converted = 20*log10(x);
                app.UIAxes_freqsig.YLabel.String = 'Magnitude (dB)';
            elseif app.preprocunit_value == 0
                app.f_signal_unit_converted = x;
                app.UIAxes_freqsig.YLabel.String = 'Magnitude (arbitrary)';
            end
        end
        %%%%%%%%%%%%%%%%%% SLT Tab - Initialise Tab %%%%%%%%%%%%%%%%%%
        function init_SLT(app)
            if ~isempty(app.signal)
                % Check if signal was modified in pre processing tab:
                b = any([app.trimmed_flag, app.resampd_flag, app.normed_flag], 'all');
                % If signal is unmodified, use original as working_signal:
                if ismember(1, b) == 0 
                    app.working_signal = app.signal;
                elseif ismember(1, b) ~= 0 % Else use working signal:
                    app.working_signal = app.working_signal;
                end

                % Check which FS to use:
                if app.resampd_flag == 0
                    app.working_fs = app.original_fs;
                elseif app.resampd_flag == 1
                    app.working_fs = app.working_fs;
                end

                % Init time resolution reduction values
                app.sltsignal_duration = length(app.working_signal) / app.working_fs;
                t_res_original = 1/app.working_fs;
                max_tres = app.sltsignal_duration / 10;
                default_tres = t_res_original * 4;
                app.MaxTimeResolutionSecondsEditField.Value = t_res_original;
                app.TimeResolutionSecondsEditField.Limits = [t_res_original, max_tres];
                app.TimeResolutionSecondsEditField.Value = default_tres;
                r = t_res_original / app.TimeResolutionSecondsEditField.Value;
                app.slt_ncolumns_reduced = length(app.working_signal) * r;

                % Init Default SLT Algorithm Settings
                nyq = app.working_fs/2;

                app.LowestFSpinner.Limits = [1, nyq-1];
                app.LowestFSpinner.Value = 10;
                app.fmin = app.LowestFSpinner.Value;
                app.HighestFSpinner.Limits = [2, nyq-1];
                app.HighestFSpinner.Value = nyq-1;
                app.fmax = app.HighestFSpinner.Value;

                app.FrequencyResolutionHzEditField.Limits = [0.05, 10];
                app.FrequencyResolutionHzEditField.Value = 0.25;
                app.fres = app.FrequencyResolutionHzEditField.Value;

                app.CyclesSpinner.Limits = [1, 10];
                app.CyclesSpinner.Value = 4;
                app.cycles = app.CyclesSpinner.Value;

                app.Upper_order_EditField.Limits = [1, 120];
                app.Upper_order_EditField.Value = 40;
                app.Lower_order_EditField.Limits = [0, 119];
                app.Lower_order_EditField.Value = 10;
                app.order = [app.Lower_order_EditField.Value, app.Upper_order_EditField.Value];

                app.SuperresolutionModeSwitch.Value = 'Multiplicative';
                if strcmp(app.SuperresolutionModeSwitch.Value, 'Multiplicative') == 1
                    app.mult = 1;
                elseif strcmp(app.SuperresolutionModeSwitch.Value, 'Additive') == 1
                    app.mult = 0;
                end

                % Init counts, Vectors etc.
                app.n_freqs = (app.fmax - app.fmin) / app.fres;
                app.SLT_f_vec = linspace(app.fmin, app.fmax, app.n_freqs);

                % Init default units
                app.SLT_units = app.SLT_SigQuantityUnitsButtonGroup.SelectedObject.Text;

                % Set default time axis limits & values
                if app.trimmed_flag == 0
                    t = app.t_vec;
                elseif app.trimmed_flag == 1
                    t = app.working_t_vec;
                end
                
                app.slt_timelim = [t(1), t(end)];
                app.EndTimesEditField_2.Limits = app.slt_timelim;
                app.StartTimesEditField_2.Limits = app.slt_timelim;
                app.StartTimesEditField_2.Value = t(1);
                app.EndTimesEditField_2.Value = t(end);

                % Set default frequency axis limits & values
                app.slt_freqlim = [app.fmin, app.fmax];
                app.LowFreqLimitHzEditField.Limits = app.slt_freqlim;
                app.HighFreqLimitHzEditField.Limits = [app.slt_freqlim(1), app.slt_freqlim(2)+1];
                app.LowFreqLimitHzEditField.Value = app.fmin;
                app.HighFreqLimitHzEditField.Value = app.fmax+1;
                
                
                % Disable plot controls until plot has been generated
                app.SLT_SigQuantityUnitsButtonGroup.Enable = "off";
                app.SLT_FAxisScalingSwitch.Enable = "off";
                app.SLT_ExportPlotButton.Enable = "off";

                % Init unit convert flag
                app.SLTunitconverted_flag = 0;

                % Test if there is sufficient memory to render SLT for the
                % signal with the selected time resolution
                memorytest(app);

                % Set init_SLT flag to on
                app.SLT_initialised_flag = 1;

            elseif isempty(app.signal)
            end
        end

        %%%%%%%%%%%%%%%%%% SLT Tab - Plot scalogram button %%%%%%%%%%%%%%%%%%
        function plotscalo(app)
            % Init Progress Bar
            wbar = waitbar(0.2, 'Please wait, computing SLT...');
            pause(0.1)

            % Refresh Time resolution
            refresh_timeresreduction(app)

            % If we don't have enough RAM, display a warning
            % and abort the transform.
            if app.hasEnoughMemory == 0 && strcmp(app.MemorySafetyLimiterSwitch.Value, 'On') == 1
                warning = warndlg(["Warning:"...
                    "Not enough RAM to compute SLT with the current settings.",...
                    "Estimated memory usage must not exceed 100%. Aborting SLT.", newline,...
                    "Try reducing the signal length, sampling frequency, ",...
                    "time resolution, frequency resolution, or frequency range."],'Warning');
                % Stop processsing
                waitfor(warning);
                close(wbar)
                return
            end

            % Update progress bar
            waitbar(0.30, wbar, 'Please wait, computing SLT...');

            app.slt = faslt_mex(app.working_signal', app.working_fs, ...
                [app.fmin, app.fmax], app.n_freqs, app.cycles, app.order,...
                app.mult);
            % Note: The above call to faslt has the argument for the input
            % signal transposed. This is because the C implementation of
            % faslt compiled within the MEX expects input to be an [n x 1] 
            % array, whereas the native version expects it as [1 x n]. The
            % rest of the app is built to dimension the signal as [1 x n], 
            % so it is transposed here.

            % Update progress bar
            waitbar(0.50, wbar, 'Please wait, computing SLT...');

            % nfaslt returns magnitude squared. Take square root to get magnitude.
            app.slt = sqrt(app.slt);

            % Downsample number of columns to the specified time resolution
            app.slt = imresize(app.slt, [app.n_freqs, app.slt_ncolumns_reduced], 'nearest');
            app.t_vec_reduced = linspace(0, app.sltsignal_duration, size(app.slt, 2));

            % Normalise magnitudes to max = 1
            app.slt = app.slt ./ max(app.slt, [], 'all');

            % make very small numbers something other than NaN or -inf
            min_realval = min(app.slt(~isinf(app.slt)), [], "all", "omitnan");
            app.slt(isnan(app.slt)) = min_realval;
            app.slt(isinf(app.slt)) = min_realval;

            % Update progress bar
            pause(0.1)
            waitbar(0.75, wbar, 'Please wait, generating plot...');

            % Set X Scale for UIAxes object
            if strcmp(app.SLT_fscale, "Linear") == 1
                app.UIAxes_SLTScalo.XScale = "linear";
            elseif strcmp(app.SLT_fscale, "Logarithmic") == 1
                app.UIAxes_SLTScalo.XScale = "log";
            end

            % intialise dynamic range control
            init_dynamicrange(app, app.slt)

            % Redraw SLT plot
            redrawSLTplot(app, app.SLT_f_vec, app.t_vec_reduced, app.slt)

            % Update progress bar
            waitbar(0.90, wbar, 'Please wait, Updating Plot...');
            pause(0.25)
            close(wbar)

            % Enable plot controls
            app.SLT_SigQuantityUnitsButtonGroup.Enable = "on";
            app.SLT_FAxisScalingSwitch.Enable = "on";
            app.SLT_ExportPlotButton.Enable = "on";
        end

        %%%%%%%%%%%%%%%%%% SLT Tab - Redraw TFR Plot %%%%%%%%%%%%%%%%%%
        function redrawSLTplot(app, f, t, mat)

            % Set colorbar label
            app.colorbar_label = [app.SLT_units, ', Normalised'];

            % Generate plot
            surf(app.UIAxes_SLTScalo, f, t, mat', EdgeColor = 'None');
            app.UIAxes_SLTScalo.XLim = [app.fmin, app.fmax+1];
            app.UIAxes_SLTScalo.YLim = [app.t_vec_reduced(1), app.t_vec_reduced(end)];
            app.UIAxes_SLTScalo.XDir = 'reverse';
            app.UIAxes_SLTScalo.YDir = 'normal';
            app.UIAxes_SLTScalo.Toolbar.Visible = 'on';
            app.UIAxes_SLTScalo.View = [90 90];
            c = colorbar(app.UIAxes_SLTScalo);
            c.Label.String = app.colorbar_label;
            drawnow;
            figure(app.UIFigure)

            % Set colorbar limits and value for dynamic range control
            clim(app.UIAxes_SLTScalo, [app.slt_maxval-app.DynamicRangearbEditField.Value, app.slt_maxval]);
            drawnow;
            figure(app.UIFigure)
        end

        %%%%%%%%%%%%%%%%%% SLT Tab - Update Signal Quantity Units %%%%%%%%%%%%%%%%%%
        function dataOUT = SLTunitconvert(app, units, dataIN)

            % Normalise magnitudes to max = 1
            dataNORM = dataIN ./ max(dataIN, [], 'all');

            % Convert units
            switch units
                case 'Magnitude (arb)'
                    dataOUT = dataNORM;
                    app.SLTunitconverted_flag = 0;
                    % Change units of dynamic range field
                    app.DynamicRangearbEditFieldLabel.Text = 'Dynamic Range (arb)';

                case 'Log Magnitude (dB)'
                    dataOUT = 20*log10(dataNORM);
                    app.SLTunitconverted_flag = 1;
                    % Change units of dynamic range field
                    app.DynamicRangearbEditFieldLabel.Text = 'Dynamic Range (dB)';

                case 'Power (W)'
                    dataOUT = dataNORM .^2;
                    app.SLTunitconverted_flag = 1;
                    % Change units of dynamic range field
                    app.DynamicRangearbEditFieldLabel.Text = 'Dynamic Range (W)';

                case 'Log Power (dBW)'
                    dataOUT = 10*log10(dataNORM .^2);
                    app.SLTunitconverted_flag = 1;
                    % Change units of dynamic range field
                    app.DynamicRangearbEditFieldLabel.Text = 'Dynamic Range (dBW)';
            end

            % make very small numbers something other than NaN or -inf
            min_realval = min(dataOUT(~isinf(dataOUT)), [], "all", "omitnan");
            dataOUT(isnan(dataOUT)) = min_realval;
            dataOUT(isinf(dataOUT)) = min_realval;

        end

        %%%%%%%%%%%%%%%%%% Audio Playback Panel - Init audio system %%%%%%%%%%%%%%%%%%
        function init_audioIO(app)

            % Check which version of signal to use - Always use original fs
            if app.trimmed_flag == 1 && app.resampd_flag == 0
                app.play_signal = app.working_signal;
            elseif app.trimmed_flag == 0 && app.resampd_flag == 0
                app.play_signal = app.signal;
            elseif app.trimmed_flag == 1 && app.resampd_flag == 1
                app.play_signal = app.signal(round(app.t_start_idx):round(app.t_end_idx));
            elseif app.trimmed_flag == 0 && app.resampd_flag == 1
                app.play_signal = app.signal;
            end

            % Populate Device Dropdown Menu
            for i = 1:numel(app.devINFO.output)
                app.AudioOutputDeviceDropDown.Items{1, i} = app.devINFO.output(i).Name;
            end
            
            % Select first option from Dropdown Menu
            app.AudioOutputDeviceDropDown.Value = app.devINFO.output(1).Name;
            app.devID = app.devINFO.output(1).ID;

            % Find the fs closest to the audio rate, that is supported by
            % the hardware:
            standard_rates = [8000, 8192, 11025, 16000, 22050, 32000, 44100,...
                47250, 48000, 50000, 88200, 96000];
            [~, rate_idx] = min(abs(standard_rates - app.original_fs));
            app.closest_HW_fs = standard_rates(rate_idx);

            % Set resampled signal bandwidth
            bw = floor(min([app.closest_HW_fs, app.original_fs]) * 0.48);

            % Resample audio to default hardware rate
            app.play_signal = easySRC(app.play_signal, app.original_fs, ...
                app.closest_HW_fs, bw);

            % Normalise amplitude of signal
            app.play_signal = app.play_signal ./ max(abs(app.play_signal));

            % Set playback fs according to playspeed
            app.play_fs = ceil(app.closest_HW_fs * app.PlaybackSpeedSpinner.Value);

            % Initialise play object
            app.player = audioplayer(app.play_signal, app.play_fs, 16, app.devID);
        end

        %%%%%%%%%%%%%%%%%% Audio Playback Panel - Play Button %%%%%%%%%%%%%%%%%%
        function playaudio(app)
            % Set playback fs at set playspeed
            app.play_fs = ceil(app.closest_HW_fs * app.PlaybackSpeedSpinner.Value);
            
            % test sample rate
%             support = audiodevinfo(0, app.devID, app.play_fs, [], []);

            % Reset fs of player object
            set(app.player, 'SampleRate', app.play_fs);

            % Disable controls
            app.AudioOutputDeviceDropDown.Enable = 'off';
            app.PlaybackSpeedSpinner.Enable = 'off';
            app.LoopPlaySwitch.Enable = 'off';
            app.VolumeSlider.Enable = 'off';

            % Depending on loop play switch, play once or in a loop
            if strcmp(app.LoopPlaySwitch.Value, 'Off') == 1 % Play once
                % Start streaming audio, block other audio
                playblocking(app.player);
            elseif strcmp(app.LoopPlaySwitch.Value, 'On') == 1  % Play in a loop
                while app.PlayButton.Value == 1
                    % Start streaming audio, block other audio
                    playblocking(app.player);
                end
            end

            % Flush event cue to force player 'running' flag to update
            drawnow
            pause(0.25)

            if ~isplaying(app.player)
                % Playbutton up
                app.PlayButton.Value = 0;
                % Re-enable other controls
                app.AudioOutputDeviceDropDown.Enable = 'on';
                app.PlaybackSpeedSpinner.Enable = 'on';
                app.LoopPlaySwitch.Enable = 'on';
                app.VolumeSlider.Enable = 'on';
                % Flush event cue to force player 'running' flag to update
                drawnow
            else
            end
        end

        %%%%%%%%%%%%%%%%%% Audio Playback Panel - Change Output Device %%%%%%%%%%%%%%%%%%
        function change_outputdevice(app)
            % Get device ID from dropdown menu name
            selected = convertCharsToStrings(app.AudioOutputDeviceDropDown.Value);
            index = find(strcmp({app.devINFO.output.Name}, selected)==1);
            app.devID = app.devINFO.output(index).ID;

            % Reset device ID of player object
            app.player = audioplayer(app.play_signal, app.play_fs, 16, app.devID);
        end

        function volumechange(app)
            if isprop(app, 'previous_gain_factor') == 0
                % Get gain scaling factor
                gain_factor = app.VolumeSlider.Value/100;

                % Apply gain scaling factor
                app.play_signal = app.play_signal .* gain_factor;

                % Initialise play object
                app.player = audioplayer(app.play_signal, app.play_fs, 16, app.devID);

                % Set previous gain factor
                app.previous_gain_factor = gain_factor;

            elseif isprop(app, 'previous_gain_factor') == 1
                % Get gain scaling factor
                gain_factor = (range([1, app.previous_gain_factor])) + (app.VolumeSlider.Value/100);

                % Apply gain scaling factor
                app.play_signal = app.play_signal .* gain_factor;

                % Initialise play object
                app.player = audioplayer(app.play_signal, app.play_fs, 16, app.devID);

                % Set previous gain factor
                app.previous_gain_factor = gain_factor;
            end
        end

        %%%%%%%%%%%%%%%%%% Superlet Scalogram Playback Panel - Backend Memory Test %%%%%%%%%%%%%%%%%%
        function memorytest(app)
            % Checks if the computer has sufficient memory to render the
            % SLT using webGL, which is the renderer for MATLAB Apps.
            % Note the memory() function used in this test is only
            % compatible with Windows OS. 

            % Call SLTmemory test, to estimate memory used to compute SLT 
            % and render the results as a surface.
            Fi = [app.fmin, app.fmax];
            Nf = ceil(range(Fi) / app.fres);
            [compute_mem_required, compmem_units] = SLTmemorytest2(length(app.working_signal), ...
                app.working_fs, Fi, Nf, app.cycles, app.order, app.mult, app.slt_ncolumns_reduced);
                        
            % Probe the host computer's available memory (bytes)
            [userview, ~] = memory;
            available = userview.MemAvailableAllArrays;

            % Convert to same units as memory requirement estimate
            switch compmem_units
                case 'Bytes'
                    % do nothing
                case 'KBytes'
                    available = available / 1000;
                case 'MBytes'
                    available = available / 1e-6;
                case 'GBytes'
                    available = available / 1e+9;
                case 'TBytes'
                    available = available / 1e+12;
            end

            % Predicted memory usage as percent of available system memory:
            percent_mem = (compute_mem_required / available) * 100;

            % Display Memory Required to plot scalogram
            app.RAM_est_EditField.Value = compute_mem_required;
            app.RAM_est_EditFieldLabel.Text = ['Estimated RAM to Compute (', compmem_units, ')'];
            app.RAM_est_PERC_EditField.Value = percent_mem;
            app.RAM_est_PERC_EditFieldLabel.Text = 'Estimated memory usage (%):';
            
            % Set "has enough memory" flag to "NO" if more than 100 percent
            % of memory allocation is used.
            app.hasEnoughMemory = percent_mem <= 100;
        end

        %%%%%%%%%%%%%%%%%% Superlet Scalogram Tab - Init time resolution reduction settings %%%%%%%%%%%%%%%%%%
        function refresh_timeresreduction(app)
            % Init time resolution reduction values
            app.sltsignal_duration = length(app.working_signal) / app.working_fs;
            t_res_original = 1/app.working_fs;
            r = t_res_original / app.TimeResolutionSecondsEditField.Value;
            app.slt_ncolumns_reduced = length(app.working_signal) * r;
        end

        %%%%%%%%%%%%%%%%%% Superlet Scalogram Tab - Init Dynamic Range Control %%%%%%%%%%%%%%%%%%
        function init_dynamicrange(app, slt_data)
            app.slt_minval = floor(min(slt_data, [], 'all'));
            app.slt_maxval = ceil(max(slt_data, [], 'all'));
            maxrange = range([app.slt_minval, app.slt_maxval]);
            app.DynamicRangearbEditField.Limits = [0, maxrange];
            app.DynamicRangearbEditField.Value = maxrange;
        end

        %%%%%%%%%%%%%%%%%% Preprocessing Tab - DC Filter Time Domain Signal %%%%%%%%%%%%%%%%%%
        function dataOUT = dcfilt(~, dataIN)
            offset = mean(dataIN);
            dataOUT = dataIN - offset;           
        end

    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Set maximum file size limit
            app.maxsiglen = 60e3;
            % Set maximmum sample rate 
            app.maxfs = 1e3;

            % Get system audio device Info
            app.devINFO = audiodevinfo;
            % Call faslt_MEX with very small dummy data - MEX runs faster
            % on second run.
            dummydata = rand(1, 1000);
            dummyfs = 100;
            dummyFi = [1, 49];
            dummyNf = 48;
            dummyc1 = 4;
            dummyo = [10, 40];
            dummymult = 1;
            dummyslt = faslt_mex(dummydata, dummyfs, dummyFi, dummyNf, dummyc1, dummyo, dummymult); %#ok<NASGU> 
            dummyslt = []; %#ok<NASGU> 
            app.SLT_initialised_flag = 0;
        end

        % Button pushed function: LoadAudioFileButton
        function LoadAudioFileButtonPushed(app, event)
            % Set home directory
            if ispc
                home = getenv('USERPROFILE');
            else
                home = getenv('HOME');
            end

            % If a signal was already loaded, reset all before re-loading
            if ~isempty(app.working_signal) || ~isempty(app.signal)
                ResetAllButtonPushed(app, matlab.ui.eventdata.ButtonPushedData);
            end

            % User file load dialog
            [app.file, app.path] = uigetfile('.wav', 'Load WAV file:', home);

            % Call file load function
            loadfile(app)
        end

        % Button pushed function: ResetAllButton
        function ResetAllButtonPushed(app, event)
            % Intiailise properties containing signals and results
            app.slt = [];
            app.sltplot = [];
            app.signal = [];
            app.f_signal = [];
            app.f_signal_unit_converted = [];
            app.f_signal_norm =[];
            app.working_signal = [];

            % Clear plots
            cla(app.UIAxes_SLTScalo);
            cla(app.UIAxes_freqsig);
            cla(app.UIAxes_timesig);

            % Reload previously selected file
            loadfile(app)

            % Flush Event Cue
            drawnow
            app.UIFigure

            % Re-Enable start and end time fields & trim buttons
            app.StartTimesEditField.Editable = 'on';
            app.EndTimesEditField.Editable = "on";
            app.TrimSignalButton.Enable = "on";

            % Re-Enable sample rate edit field
            app.SampleRateHzEditField.Editable = "on";
            app.ResampleButton.Enable = "on";

            % Re-Enable normalize button
            app.preproc_NormalizeButton.Enable = 'on';

            % Set Preprocessing flags to 0
            app.resampd_flag = 0;
            app.trimmed_flag = 0;
            app.normed_flag = 0;
            app.SLT_initialised_flag = 0;
            
            % Initialise the audio output menu
            init_audioIO(app)
            
            % Reinitialise Superlet Scalogram tab
            init_SLT(app)
        end

        % Value changed function: StartTimesEditField
        function StartTimesEditFieldValueChanged(app, event)
            % Get new values
            app.t_start = app.StartTimesEditField.Value;

            if app.trimmed_flag == 0 && app.resampd_flag == 0
                % Get indices from values
                [~, app.t_start_idx] = min(abs(app.t_vec - app.t_start));
            elseif app.trimmed_flag == 1 || app.resampd_flag == 1
                % Get indices from values
                [~, app.t_start_idx] = min(abs(app.working_t_vec - app.t_start));
            end
        end

        % Value changed function: EndTimesEditField
        function EndTimesEditFieldValueChanged(app, event)
            % Get new value
            app.t_end = app.EndTimesEditField.Value;

            if app.trimmed_flag == 0 && app.resampd_flag == 0
                % Get indices from values
                [~, app.t_end_idx] = min(abs(app.t_vec - app.t_end));
            elseif app.trimmed_flag == 1 || app.resampd_flag == 1
                % Get indices from values
                [~, app.t_end_idx] = min(abs(app.working_t_vec - app.t_end));
            end
        end

        % Button pushed function: TrimSignalButton
        function TrimSignalButtonPushed(app, event)
            if app.StartTimesEditField.Value ~= app.t_vec(1) || app.EndTimesEditField.Value ~= app.t_vec(end)
                % Trim Signal
                trimsig(app)

                % Update plot with trimmed signal
                redrawTimeplot(app, app.working_t_vec, app.working_signal)

                % Compute Mag Spectrum
                computemagspect(app, app.working_signal)
    
                % Update magnitude response plot
                redrawMAGplot(app, app.f_signal)

                % If normalised before trimming, re-enable normalisation
                % button
                if app.normed_flag == 1
                    feval(app.preproc_NormalizeButton.ButtonPushedFcn, app, []);
                end

                % Update playback file & re-init audioplayer
                init_audioIO(app)
            else
            end
        end

        % Value changed function: SampleRateHzEditField
        function SampleRateHzEditFieldValueChanged(app, event)
            % Set new_fs property from UI element
            app.working_fs = app.SampleRateHzEditField.Value;

            % Update fmax field
            app.UpperFreqLimitHzEditField.Value = app.working_fs / 2;

        end

        % Button pushed function: ResampleButton
        function ResampleButtonPushed(app, event)
            if app.SampleRateHzEditField.Value ~= app.original_fs
                % Resampling progress bar
                wbar = waitbar(0.25,'Please wait, resampling audio...');

                % Resample signal
                resamp(app)

                % Update progress bar
                waitbar(0.50, wbar, 'Please wait, resampling audio...')

                % Compute magnitude spectrum
                computemagspect(app, app.working_signal)

                % Update mag plot units
                preproc_mag_unitupdate(app)

                % Update magnitude spectrum plot
                redrawMAGplot(app, app.f_signal)

                % Update Time Domain Signal Plot
                redrawTimeplot(app, app.working_t_vec , app.working_signal)

                % Set start and end time fields & trim button to read-only
                app.SampleRateHzEditField.Editable = 'off';
                app.ResampleButton.Enable = "off";

                % If normalised before resampling, re-enable normalisation
                % button
                if app.normed_flag == 1
                    feval(app.preproc_NormalizeButton.ButtonPushedFcn, app, []);
                end

                % Update playback file & re-init audioplayer
                init_audioIO(app)
                
                % Update progress bar
                waitbar(0.75, wbar, 'Please wait, resampling audio...')
                close(wbar)
            else
            end
        end

        % Button pushed function: preproc_NormalizeButton
        function preproc_NormalizeButtonPushed(app, event)
            % Normalize the signal
            preproc_norm(app)

            % Update unit conversion after norming.
            preproc_mag_unitupdate(app)

            %
            if app.trimmed_flag == 0 && app.resampd_flag == 0
                t = app.t_vec;
            elseif app.trimmed_flag == 1 || app.resampd_flag == 1
                t = app.working_t_vec;
            end

            % Update time plot with
            redrawTimeplot(app, t, app.working_signal)

            % Update magnitude spectrum plot
            redrawMAGplot(app, app.f_signal_unit_converted) % f_signal_unit_converted

            app.preproc_NormalizeButton.Enable = 'off';
        end

        % Value changed function: preproc_FAxisScaleSwitch
        function preproc_FAxisScaleSwitchValueChanged(app, event)
            % Get value from UI element
            value = app.preproc_FAxisScaleSwitch.Value;

            preprocfaxisswitch(app, value)

            drawnow;
            figure(app.UIFigure)
        end

        % Value changed function: MagnitudeUnitsSwitch
        function MagnitudeUnitsSwitchValueChanged(app, event)
            % Get value from UI element
            value = app.MagnitudeUnitsSwitch.Value;

            % Convert value to type: logical: 1 for log, 0 for lin
            app.preprocunit_value = strcmp(value, 'Log (dB)');

            % Update units
            preproc_mag_unitupdate(app)

            % Redraw magnitude spectrum plot
            redrawMAGplot(app, app.f_signal_unit_converted)
        end

        % Button pushed function: Preproc_ExportPlotsButton
        function Preproc_ExportPlotsButtonPushed(app, event)
            % Construct save name and path
            savepath = uigetdir(app.path, 'Select Save Directory');
            if strcmp(app.SLTExportFormatDropDown.Value, 'JPEG') == 1
                extension = '.jpg';
            elseif strcmp(app.SLTExportFormatDropDown.Value, 'PNG') == 1
                extension = '.png';
            elseif strcmp(app.SLTExportFormatDropDown.Value, 'TIFF') == 1
                extension = '.tif';
            elseif strcmp(app.SLTExportFormatDropDown.Value, 'PDF') == 1
                extension = '.pdf';
            elseif strcmp(app.SLTExportFormatDropDown.Value, 'EPS') == 1
                extension = '.eps';
            else
                return
            end
            savename_time = ['TimeDomain_', app.file(1:end-4), extension];
            savename_freq = ['FreqDomain_', app.file(1:end-4), extension];
            % Save files
            exportgraphics(app.UIAxes_timesig, fullfile(savepath, savename_time))
            exportgraphics(app.UIAxes_freqsig, fullfile(savepath, savename_freq))
        end

        % Button down function: SuperletScalogramTab
        function SuperletScalogramTabButtonDown(app, event)
            % Warn if sample rate is too high and likely to cause crashes.
            if (app.resampd_flag == 0 && app.original_fs > app.maxfs) ||...
                    app.resampd_flag == 1 && app.working_fs > app.maxfs
                % Display warning
                warning = warndlg(["Warning!", newline,...
                    "The audio sampling frequency is greater than 1kHz.",...
                    "This may cause the software to hang or crash.", newline,...
                    "The maximum supported audio sample rate is 1kHz", newline,...
                    "Please resample to a lower rate before analysing."], "Warning!");
                % Stop processsing
                waitfor(warning);
                % Return to Preproc Tab
                app.TabGroup.SelectedTab = app.PreProcessingTab;
                % Halt This callback
                return
            end

            % Check Length of signal, if longer than "maxsiglen" samples,
            % then abort loading. This will use too much memory.
            if (app.trimmed_flag == 0 && app.resampd_flag == 0 && length(app.signal) > app.maxsiglen) ||...
                    ((app.trimmed_flag == 1 || app.resampd_flag == 1) && length(app.working_signal) > app.maxsiglen)
                % Display warning
                warning = warndlg(["Warning!", newline,... 
                    "The selected audio file is either too long, or has a very high sample rate. Computing the SLT for this file will consume more memory than available.", newline,...
                    "The maximum supported audio length is 60,000 samples (60-seconds at the maximum allowed sample rate of 1kHz).", newline,...
                    "Please trim and/or resample to a lower rate before analysing."],"Warning!");
                % Stop processsing
                waitfor(warning);
                % Return to Preproc Tab
                app.TabGroup.SelectedTab = app.PreProcessingTab;
                % Halt This callback
                return
            end

            if app.SLT_initialised_flag == 0
            init_SLT(app)
            else
            end

        end

        % Value changed function: LowestFSpinner
        function LowestFSpinnerValueChanged(app, event)
            app.fmin = app.LowestFSpinner.Value;
            app.LowFreqLimitHzEditField.Value = app.fmin;

            % Update memory test result
            memorytest(app);
        end

        % Value changed function: HighestFSpinner
        function HighestFSpinnerValueChanged(app, event)
            app.fmax = app.HighestFSpinner.Value;
            app.HighFreqLimitHzEditField.Value = app.fmax+1;

            % Update memory test result
            memorytest(app);
        end

        % Value changed function: CyclesSpinner
        function CyclesSpinnerValueChanged(app, event)
            app.cycles = app.CyclesSpinner.Value;

        end

        % Value changed function: Lower_order_EditField
        function Lower_order_EditFieldValueChanged(app, event)
            app.order(1) = app.Lower_order_EditField.Value;

        end

        % Value changed function: Upper_order_EditField
        function Upper_order_EditFieldValueChanged(app, event)
            app.order(2) = app.Upper_order_EditField.Value;

        end

        % Value changed function: SuperresolutionModeSwitch
        function SuperresolutionModeSwitchValueChanged(app, event)
            if strcmp(app.SuperresolutionModeSwitch.Value, 'Multiplicative') == 1
                app.mult = 1;
            elseif strcmp(app.SuperresolutionModeSwitch.Value, 'Additive') == 1
                app.mult = 0;
            end
        end

        % Value changed function: FrequencyResolutionHzEditField
        function FrequencyResolutionHzEditFieldValueChanged(app, event)
            app.fres = app.FrequencyResolutionHzEditField.Value;

            % Update memory test result
            memorytest(app);
        end

        % Value changed function: TimeResolutionSecondsEditField
        function TimeResolutionSecondsEditFieldValueChanged(app, event)
            % Refresh time resolution parameters
            refresh_timeresreduction(app)

            % Update memory test result
            memorytest(app);
        end

        % Button pushed function: PlotScalogramButton
        function PlotScalogramButtonPushed(app, event)
            % Plot scalogram
            plotscalo(app)

            % Reset unit selection button to magnitude
            app.SLT_SigQuantityUnitsButtonGroup.SelectedObject = app.MagnitudearbButton;
        end

        % Value changed function: SLT_FAxisScalingSwitch
        function SLT_FAxisScalingSwitchValueChanged(app, event)
            app.SLT_fscale = app.SLT_FAxisScalingSwitch.Value;
            % Progress Bar
            wbar = waitbar(0.50, 'Please wait, Updating Plot...');
            pause(0.5)

            % Set X Scale & Ticks for UIAxes object
            if strcmp(app.SLT_fscale, "Linear") == 1
                app.UIAxes_SLTScalo.XScale = "linear";
            elseif strcmp(app.SLT_fscale, "Logarithmic") == 1
                app.UIAxes_SLTScalo.XScale = "log";
            end
            drawnow;
            figure(app.UIFigure)

            % Update progress bar
            waitbar(0.90, wbar, 'Please wait, Updating Plot...');
            pause(0.25)
            close(wbar)
        end

        % Selection changed function: SLT_SigQuantityUnitsButtonGroup
        function SLT_SigQuantityUnitsButtonGroupSelectionChanged(app, event)
            app.SLT_units = app.SLT_SigQuantityUnitsButtonGroup.SelectedObject.Text;

            % Progress Bar
            wbar = waitbar(0.25, 'Please wait, Updating Plot...');
            pause(0.1)

            % Convert Units
            app.sltplot = SLTunitconvert(app, app.SLT_units, app.slt);

            % Update progress bar
            waitbar(0.75, wbar, 'Please wait, Updating Plot...');
            pause(0.1)

            % intialise dynamic range control
            init_dynamicrange(app, app.sltplot)

            % Redraw SLT plot
            redrawSLTplot(app, app.SLT_f_vec, app.t_vec_reduced, app.sltplot)

            % Update progress bar
            waitbar(0.90, wbar, 'Please wait, Updating Plot...');
            pause(0.1)
            close(wbar)

        end

        % Button pushed function: SLT_ExportPlotButton
        function SLT_ExportPlotButtonPushed(app, event)
            % Construct save name and path
            savepath = uigetdir(app.path, 'Select Save Directory');
            if strcmp(app.SLTExportFormatDropDown.Value, 'JPEG') == 1
                extension = '.jpg';
            elseif strcmp(app.SLTExportFormatDropDown.Value, 'PNG') == 1
                extension = '.png';
            elseif strcmp(app.SLTExportFormatDropDown.Value, 'TIFF') == 1
                extension = '.tif';
            elseif strcmp(app.SLTExportFormatDropDown.Value, 'PDF') == 1
                extension = '.pdf';
            elseif strcmp(app.SLTExportFormatDropDown.Value, 'EPS') == 1
                extension = '.eps';
            else
                return
            end
            savename_scalo = ['Superlet_Scalogram_', app.file(1:end-4), extension];
            % Save file
            exportgraphics(app.UIAxes_SLTScalo, fullfile(savepath, savename_scalo))
        end

        % Value changed function: DynamicRangearbEditField
        function DynamicRangearbEditFieldValueChanged(app, event)
            if app.SLTunitconverted_flag == 1
                redrawSLTplot(app, app.SLT_f_vec, app.t_vec_reduced, app.sltplot)
            elseif app.SLTunitconverted_flag == 0
                redrawSLTplot(app, app.SLT_f_vec, app.t_vec_reduced, app.slt)
            end

        end

        % Value changed function: AudioOutputDeviceDropDown
        function AudioOutputDeviceDropDownValueChanged(app, event)
            change_outputdevice(app)
        end

        % Value changed function: PlayButton
        function PlayButtonValueChanged(app, event)
            if isempty(app.play_signal) == 0
                if app.PlayButton.Value == 1
                    % Start playback
                    playaudio(app)
                elseif app.PlayButton.Value == 0
                    % Stop playback
                    stop(app.player)
                    % Enable controls
                    app.AudioOutputDeviceDropDown.Enable = 'on';
                    app.PlayButton.Enable = 'on';
                    app.PlaybackSpeedSpinner.Enable = 'on';
                    app.LoopPlaySwitch.Enable = 'on';
                    app.VolumeSlider.Enable = 'on';
                end
            else
                % If no audio is loaded, just reset button state and end.
                app.PlayButton.Value = 0;
            end

        end

        % Value changed function: VolumeSlider
        function VolumeSliderValueChanged(app, event)
            volumechange(app)
        end

        % Value changed function: StartTimesEditField_2
        function StartTimesEditField_2ValueChanged(app, event)
            % Set X axis lower limit            
            app.slt_timelim(1) = app.StartTimesEditField_2.Value;
            app.UIAxes_SLTScalo.YLim = app.slt_timelim;
            drawnow;
            figure(app.UIFigure)
        end

        % Value changed function: EndTimesEditField_2
        function EndTimesEditField_2ValueChanged(app, event)
            % Set X axis lower limit            
            app.slt_timelim(2) = app.EndTimesEditField_2.Value;
            app.UIAxes_SLTScalo.YLim = app.slt_timelim;
            drawnow;
            figure(app.UIFigure)
        end

        % Value changed function: LowFreqLimitHzEditField
        function LowFreqLimitHzEditFieldValueChanged(app, event)
            app.slt_freqlim(1) = app.LowFreqLimitHzEditField.Value;
            app.UIAxes_SLTScalo.XLim = app.slt_freqlim;
            drawnow;
            figure(app.UIFigure)
        end

        % Value changed function: HighFreqLimitHzEditField
        function HighFreqLimitHzEditFieldValueChanged(app, event)
            app.slt_freqlim(2) = app.HighFreqLimitHzEditField.Value;
            app.UIAxes_SLTScalo.XLim = app.slt_freqlim;
            drawnow;
            figure(app.UIFigure)
        end

        % Button pushed function: ResetAllLimitsButton
        function ResetAllLimitsButtonPushed(app, event)
            
                % Set default time axis limits & values
                app.slt_timelim = [app.t_vec(1), app.t_vec(end)];
                app.EndTimesEditField_2.Limits = app.slt_timelim;
                app.StartTimesEditField_2.Limits = app.slt_timelim;
                app.StartTimesEditField_2.Value = app.t_vec(1);
                app.EndTimesEditField_2.Value = app.t_vec(end);

                % Set default frequency axis limits & values
                app.slt_freqlim = [app.fmin, app.fmax];
                app.LowFreqLimitHzEditField.Limits = app.slt_freqlim;
                app.HighFreqLimitHzEditField.Limits = app.slt_freqlim;
                app.LowFreqLimitHzEditField.Value = app.fmin;
                app.HighFreqLimitHzEditField.Value = app.fmax;

                % Reset Limits
                app.UIAxes_SLTScalo.YLim = app.slt_timelim;
                app.UIAxes_SLTScalo.XLim = app.slt_freqlim;
                drawnow;
                figure(app.UIFigure)
        end

        % Value changed function: MemorySafetyLimiterSwitch
        function MemorySafetyLimiterSwitchValueChanged(app, event)
            warndlg(["Warning!", ...
                "Disabling the memory limiter will allow the SLT to consume more " + ...
                "memory than is available. This may cause system crashes and hangs"],'Warning');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.PreProcessingTab = uitab(app.TabGroup);
            app.PreProcessingTab.Title = 'Pre-Processing';
            app.PreprocessingSettingsPanel = uipanel(app.PreProcessingTab);
            app.PreprocessingSettingsPanel.Title = 'Pre-processing Settings';
            app.TrimPanel = uipanel(app.PreprocessingSettingsPanel);
            app.TrimSignalButton = uibutton(app.TrimPanel, 'push');
            app.TrimSignalButton.ButtonPushedFcn = createCallbackFcn(app, @TrimSignalButtonPushed, true);
            app.TrimSignalButton.Text = 'Trim Signal';
            app.StartTimesEditFieldLabel = uilabel(app.TrimPanel);
            app.StartTimesEditFieldLabel.Text = 'Start Time (s)';
            app.StartTimesEditField = uieditfield(app.TrimPanel, 'numeric');
            app.StartTimesEditField.ValueChangedFcn = createCallbackFcn(app, @StartTimesEditFieldValueChanged, true);
            app.EndTimesEditFieldLabel = uilabel(app.TrimPanel);
            app.EndTimesEditFieldLabel.Text = 'End Time (s)';
            app.EndTimesEditField = uieditfield(app.TrimPanel, 'numeric');
            app.EndTimesEditField.ValueChangedFcn = createCallbackFcn(app, @EndTimesEditFieldValueChanged, true);
            app.ResampPanel = uipanel(app.PreprocessingSettingsPanel);
            app.SampleRateHzEditFieldLabel = uilabel(app.ResampPanel);
            app.SampleRateHzEditFieldLabel.Text = 'Sample Rate (Hz)';
            app.SampleRateHzEditField = uieditfield(app.ResampPanel, 'numeric');
            app.SampleRateHzEditField.ValueDisplayFormat = '%.1f';
            app.SampleRateHzEditField.ValueChangedFcn = createCallbackFcn(app, @SampleRateHzEditFieldValueChanged, true);
            app.UpperFreqLimitHzEditFieldLabel = uilabel(app.ResampPanel);
            app.UpperFreqLimitHzEditFieldLabel.Text = 'Upper Freq. Limit (Hz)';
            app.UpperFreqLimitHzEditField = uieditfield(app.ResampPanel, 'numeric');
            app.UpperFreqLimitHzEditField.Editable = 'off';
            app.ResampleButton = uibutton(app.ResampPanel, 'push');
            app.ResampleButton.ButtonPushedFcn = createCallbackFcn(app, @ResampleButtonPushed, true);
            app.ResampleButton.Text = 'Resample';
            app.NormPanel = uipanel(app.PreprocessingSettingsPanel);
            app.preproc_NormalizeButton = uibutton(app.NormPanel, 'push');
            app.preproc_NormalizeButton.ButtonPushedFcn = createCallbackFcn(app, @preproc_NormalizeButtonPushed, true);
            app.preproc_NormalizeButton.Text = 'Normalise';
            app.MagRespAxisScalingPanel = uipanel(app.PreprocessingSettingsPanel);
            app.FrequencyAxisScaleSwitchLabel = uilabel(app.MagRespAxisScalingPanel);
            app.FrequencyAxisScaleSwitchLabel.Text = 'Frequency Axis Scale';
            app.preproc_FAxisScaleSwitch = uiswitch(app.MagRespAxisScalingPanel, 'slider');
            app.preproc_FAxisScaleSwitch.Items = {'Lin', 'Log'};
            app.preproc_FAxisScaleSwitch.ValueChangedFcn = createCallbackFcn(app, @preproc_FAxisScaleSwitchValueChanged, true);
            app.preproc_FAxisScaleSwitch.Value = 'Lin';
            app.MagnitudeUnitsSwitchLabel = uilabel(app.MagRespAxisScalingPanel);
            app.MagnitudeUnitsSwitchLabel.Text = 'Magnitude Units';
            app.MagnitudeUnitsSwitch = uiswitch(app.MagRespAxisScalingPanel, 'slider');
            app.MagnitudeUnitsSwitch.Items = {'Linear', 'Log (dB)'};
            app.MagnitudeUnitsSwitch.ValueChangedFcn = createCallbackFcn(app, @MagnitudeUnitsSwitchValueChanged, true);
            app.MagnitudeUnitsSwitch.Value = 'Linear';
            app.Panel = uipanel(app.PreprocessingSettingsPanel);
            app.ResetAllButton = uibutton(app.Panel, 'push');
            app.ResetAllButton.ButtonPushedFcn = createCallbackFcn(app, @ResetAllButtonPushed, true);
            app.ResetAllButton.Text = 'Reset All';
            app.Preproc_ExportPlotsButton = uibutton(app.Panel, 'push');
            app.Preproc_ExportPlotsButton.ButtonPushedFcn = createCallbackFcn(app, @Preproc_ExportPlotsButtonPushed, true);
            app.Preproc_ExportPlotsButton.Text = 'Export Plots';
            app.Preproc_exportDropDown = uidropdown(app.Panel);
            app.Preproc_exportDropDown.Items = {'JPEG', 'PNG', 'TIFF', 'PDF', 'EPS'};
            app.Preproc_exportDropDown.Value = 'JPEG';
            app.LoadFilePanel = uipanel(app.PreProcessingTab);
            app.LoadAudioFileButton = uibutton(app.LoadFilePanel, 'push');
            app.LoadAudioFileButton.ButtonPushedFcn = createCallbackFcn(app, @LoadAudioFileButtonPushed, true);
            app.LoadAudioFileButton.Text = 'Load Audio File';
            app.Panel_timesig = uipanel(app.PreProcessingTab);
            app.Panel_timesig.Title = 'Signal Plot';
            app.UIAxes_timesig = uiaxes(app.Panel_timesig);
            title(app.UIAxes_timesig, 'Time Domain Waveform')
            xlabel(app.UIAxes_timesig, 'Time (seconds)')
            ylabel(app.UIAxes_timesig, 'Amplitude (Arbitrary)')
            zlabel(app.UIAxes_timesig, 'Z')
            app.UIAxes_freqsig = uiaxes(app.Panel_timesig);
            title(app.UIAxes_freqsig, 'Magnitude Spectrum')
            xlabel(app.UIAxes_freqsig, 'Frequency (Hz)')
            ylabel(app.UIAxes_freqsig, 'Magnitude (arbitrary)')
            zlabel(app.UIAxes_freqsig, 'Z')
            app.SuperletScalogramTab = uitab(app.TabGroup);
            app.SuperletScalogramTab.Title = 'Superlet Scalogram';
            app.SuperletScalogramTab.ButtonDownFcn = createCallbackFcn(app, @SuperletScalogramTabButtonDown, true);
            app.Panel_2 = uipanel(app.SuperletScalogramTab);
            app.AlgorithmSettingsPanel = uipanel(app.Panel_2);
            app.AlgorithmSettingsPanel.Title = 'Algorithm Settings';
            app.TabGroup2 = uitabgroup(app.AlgorithmSettingsPanel);
            app.BasicSettingsTab = uitab(app.TabGroup2);
            app.BasicSettingsTab.Title = 'Basic Settings';
            app.GridLayout = uigridlayout(app.BasicSettingsTab);
            app.PlotScalogramButton = uibutton(app.GridLayout, 'push');
            app.PlotScalogramButton.ButtonPushedFcn = createCallbackFcn(app, @PlotScalogramButtonPushed, true);
            app.PlotScalogramButton.Text = 'Plot Scalogram';
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.LowestFrequencyOfInterestHzSpinnerLabel = uilabel(app.GridLayout2);
            app.LowestFrequencyOfInterestHzSpinnerLabel.Text = 'Lowest Frequency Of Interest (Hz) ';
            app.LowestFSpinner = uispinner(app.GridLayout2);
            app.LowestFSpinner.ValueChangedFcn = createCallbackFcn(app, @LowestFSpinnerValueChanged, true);
            app.HighestFrequencyOfInterestHzSpinnerLabel = uilabel(app.GridLayout2);
            app.HighestFrequencyOfInterestHzSpinnerLabel.Text = 'Highest Frequency Of Interest (Hz) ';
            app.HighestFSpinner = uispinner(app.GridLayout2);
            app.HighestFSpinner.ValueChangedFcn = createCallbackFcn(app, @HighestFSpinnerValueChanged, true);
            app.FrequencyResolutionHzEditFieldLabel = uilabel(app.GridLayout2);
            app.FrequencyResolutionHzEditFieldLabel.Text = 'Frequency Resolution (Hz)';
            app.FrequencyResolutionHzEditField = uieditfield(app.GridLayout2, 'numeric');
            app.FrequencyResolutionHzEditField.ValueChangedFcn = createCallbackFcn(app, @FrequencyResolutionHzEditFieldValueChanged, true);
            app.TimeResolutionSecondsEditFieldLabel = uilabel(app.GridLayout2);
            app.TimeResolutionSecondsEditFieldLabel.Text = 'Time Resolution (Seconds)';
            app.TimeResolutionSecondsEditField = uieditfield(app.GridLayout2, 'numeric');
            app.TimeResolutionSecondsEditField.ValueChangedFcn = createCallbackFcn(app, @TimeResolutionSecondsEditFieldValueChanged, true);
            app.TimeResolutionSecondsEditField.Value = 0.01;
            app.MaxTimeResolutionSecondsEditFieldLabel = uilabel(app.GridLayout2);
            app.MaxTimeResolutionSecondsEditFieldLabel.Text = 'Max. Time Resolution (Seconds)';
            app.MaxTimeResolutionSecondsEditField = uieditfield(app.GridLayout2, 'numeric');
            app.MaxTimeResolutionSecondsEditField.Editable = 'off';
            app.RAM_est_PERC_EditFieldLabel = uilabel(app.GridLayout2);
            app.RAM_est_PERC_EditFieldLabel.Text = 'Estimated Memory Usage (%)';
            app.RAM_est_PERC_EditField = uieditfield(app.GridLayout2, 'numeric');
            app.RAM_est_PERC_EditField.ValueDisplayFormat = '%.0f';
            app.RAM_est_PERC_EditField.Editable = 'off';
            app.AdvancedSettingsTab = uitab(app.TabGroup2);
            app.AdvancedSettingsTab.Title = 'Advanced Settings';
            app.GridLayout3 = uigridlayout(app.AdvancedSettingsTab);
            app.GridLayout4 = uigridlayout(app.GridLayout3);
            app.SuperresolutionModeSwitchLabel = uilabel(app.GridLayout4);
            app.SuperresolutionModeSwitchLabel.Text = 'Superresolution Mode';
            app.SuperresolutionModeSwitch = uiswitch(app.GridLayout4, 'slider');
            app.SuperresolutionModeSwitch.Items = {'Additive', 'Multiplicative'};
            app.SuperresolutionModeSwitch.ValueChangedFcn = createCallbackFcn(app, @SuperresolutionModeSwitchValueChanged, true);
            app.SuperresolutionModeSwitch.Value = 'Multiplicative';
            app.MemorySafetyLimiterSwitchLabel = uilabel(app.GridLayout4);
            app.MemorySafetyLimiterSwitchLabel.Text = 'Memory Safety Limiter';
            app.MemorySafetyLimiterSwitch = uiswitch(app.GridLayout4, 'slider');
            app.MemorySafetyLimiterSwitch.ValueChangedFcn = createCallbackFcn(app, @MemorySafetyLimiterSwitchValueChanged, true);
            app.MemorySafetyLimiterSwitch.Value = 'On';
            app.GridLayout8 = uigridlayout(app.GridLayout4);
            app.RAM_est_EditFieldLabel = uilabel(app.GridLayout8);
            app.RAM_est_EditFieldLabel.Text = 'Estimated Memory Usage';
            app.RAM_est_EditField = uieditfield(app.GridLayout8, 'numeric');
            app.RAM_est_EditField.Editable = 'off';
            app.GridLayout7 = uigridlayout(app.GridLayout3);
            app.LowerSuperresolutionorderEditFieldLabel = uilabel(app.GridLayout7);
            app.LowerSuperresolutionorderEditFieldLabel.Text = 'Lower Superresolution order ';
            app.Lower_order_EditField = uieditfield(app.GridLayout7, 'numeric');
            app.Lower_order_EditField.Limits = [1 100];
            app.Lower_order_EditField.ValueChangedFcn = createCallbackFcn(app, @Lower_order_EditFieldValueChanged, true);
            app.Lower_order_EditField.Value = 10;
            app.UpperSuperresolutionorderEditFieldLabel = uilabel(app.GridLayout7);
            app.UpperSuperresolutionorderEditFieldLabel.Text = 'Upper Superresolution order ';
            app.Upper_order_EditField = uieditfield(app.GridLayout7, 'numeric');
            app.Upper_order_EditField.Limits = [1 100];
            app.Upper_order_EditField.ValueChangedFcn = createCallbackFcn(app, @Upper_order_EditFieldValueChanged, true);
            app.Upper_order_EditField.Value = 10;
            app.InitialNumberofSuperletCyclesSpinnerLabel = uilabel(app.GridLayout7);
            app.InitialNumberofSuperletCyclesSpinnerLabel.Text = 'Initial Number of Superlet Cycles';
            app.CyclesSpinner = uispinner(app.GridLayout7);
            app.CyclesSpinner.ValueChangedFcn = createCallbackFcn(app, @CyclesSpinnerValueChanged, true);
            app.PlotSettingsPanel = uipanel(app.Panel_2);
            app.PlotSettingsPanel.Title = 'Plot Settings';
            app.GridLayout9 = uigridlayout(app.PlotSettingsPanel);
            app.GridLayout10 = uigridlayout(app.GridLayout9);
            app.SLT_SigQuantityUnitsButtonGroup = uibuttongroup(app.GridLayout10);
            app.SLT_SigQuantityUnitsButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @SLT_SigQuantityUnitsButtonGroupSelectionChanged, true);
            app.SLT_SigQuantityUnitsButtonGroup.Title = 'Signal Quantity Units';
            app.MagnitudearbButton = uitogglebutton(app.SLT_SigQuantityUnitsButtonGroup);
            app.MagnitudearbButton.Text = 'Magnitude (arb)';
            app.MagnitudearbButton.Value = true;
            app.LogMagnitudedBButton = uitogglebutton(app.SLT_SigQuantityUnitsButtonGroup);
            app.LogMagnitudedBButton.Text = 'Log Magnitude (dB)';
            app.PowerWButton = uitogglebutton(app.SLT_SigQuantityUnitsButtonGroup);
            app.PowerWButton.Text = 'Power (W)';
            app.LogPowerdBWButton = uitogglebutton(app.SLT_SigQuantityUnitsButtonGroup);
            app.LogPowerdBWButton.Text = 'Log Power (dBW)';
            app.AxisLimitsPanel = uipanel(app.GridLayout10);
            app.AxisLimitsPanel.Title = 'Axis Limits';
            app.ResetAllLimitsButton = uibutton(app.AxisLimitsPanel, 'push');
            app.ResetAllLimitsButton.ButtonPushedFcn = createCallbackFcn(app, @ResetAllLimitsButtonPushed, true);
            app.ResetAllLimitsButton.Text = 'Reset All Limits';
            app.DynamicRangearbEditFieldLabel = uilabel(app.AxisLimitsPanel);
            app.DynamicRangearbEditFieldLabel.Text = 'Dynamic Range (arb)';
            app.DynamicRangearbEditField = uieditfield(app.AxisLimitsPanel, 'numeric');
            app.DynamicRangearbEditField.ValueChangedFcn = createCallbackFcn(app, @DynamicRangearbEditFieldValueChanged, true);
            app.StartTimesEditField_2Label = uilabel(app.AxisLimitsPanel);
            app.StartTimesEditField_2Label.Text = 'Start Time (s)';
            app.StartTimesEditField_2 = uieditfield(app.AxisLimitsPanel, 'numeric');
            app.StartTimesEditField_2.ValueChangedFcn = createCallbackFcn(app, @StartTimesEditField_2ValueChanged, true);
            app.EndTimesEditField_2Label = uilabel(app.AxisLimitsPanel);
            app.EndTimesEditField_2Label.Text = 'End Time (s)';
            app.EndTimesEditField_2 = uieditfield(app.AxisLimitsPanel, 'numeric');
            app.EndTimesEditField_2.ValueChangedFcn = createCallbackFcn(app, @EndTimesEditField_2ValueChanged, true);
            app.LowFreqLimitHzEditFieldLabel = uilabel(app.AxisLimitsPanel);
            app.LowFreqLimitHzEditFieldLabel.Text = 'Low Freq. Limit (Hz)';
            app.LowFreqLimitHzEditField = uieditfield(app.AxisLimitsPanel, 'numeric');
            app.LowFreqLimitHzEditField.ValueChangedFcn = createCallbackFcn(app, @LowFreqLimitHzEditFieldValueChanged, true);
            app.HighFreqLimitHzEditFieldLabel = uilabel(app.AxisLimitsPanel);
            app.HighFreqLimitHzEditFieldLabel.Text = 'High Freq. Limit (Hz)';
            app.HighFreqLimitHzEditField = uieditfield(app.AxisLimitsPanel, 'numeric');
            app.HighFreqLimitHzEditField.ValueChangedFcn = createCallbackFcn(app, @HighFreqLimitHzEditFieldValueChanged, true);
            app.GridLayout11 = uigridlayout(app.GridLayout9);
            app.SLT_ExportPlotButton = uibutton(app.GridLayout11, 'push');
            app.SLT_ExportPlotButton.ButtonPushedFcn = createCallbackFcn(app, @SLT_ExportPlotButtonPushed, true);
            app.SLT_ExportPlotButton.Text = 'Export Plot';
            app.SLTExportFormatDropDown = uidropdown(app.GridLayout11);
            app.SLTExportFormatDropDown.Items = {'JPEG', 'PNG', 'TIFF', 'PDF', 'EPS'};
            app.SLTExportFormatDropDown.Value = 'JPEG';
            app.GridLayout12 = uigridlayout(app.GridLayout11);
            app.FrequencyAxisScalingSwitchLabel = uilabel(app.GridLayout12);
            app.FrequencyAxisScalingSwitchLabel.Text = 'Frequency Axis Scaling';
            app.SLT_FAxisScalingSwitch = uiswitch(app.GridLayout12, 'slider');
            app.SLT_FAxisScalingSwitch.Items = {'Linear', 'Logarithmic'};
            app.SLT_FAxisScalingSwitch.ValueChangedFcn = createCallbackFcn(app, @SLT_FAxisScalingSwitchValueChanged, true);
            app.SLT_FAxisScalingSwitch.Value = 'Linear';
            app.TimeFrequencyDomainSignalPlotPanel_3 = uipanel(app.SuperletScalogramTab);
            app.TimeFrequencyDomainSignalPlotPanel_3.Title = 'Time-Frequency Domain Signal Plot ';
            app.UIAxes_SLTScalo = uiaxes(app.TimeFrequencyDomainSignalPlotPanel_3);
            xlabel(app.UIAxes_SLTScalo, 'Frequency (Hz)')
            ylabel(app.UIAxes_SLTScalo, 'Time (seconds)')
            zlabel(app.UIAxes_SLTScalo, 'Z')
            app.UIAxes_SLTScalo.XDir = 'reverse';
            app.UIAxes_SLTScalo.YDir = 'reverse';
            app.AudioPlaybackPanel = uipanel(app.UIFigure);
            app.AudioPlaybackPanel.Title = 'Audio Playback';
            app.AudioOutputDeviceDropDownLabel = uilabel(app.AudioPlaybackPanel);
            app.AudioOutputDeviceDropDownLabel.Text = 'Audio Output Device';
            app.AudioOutputDeviceDropDown = uidropdown(app.AudioPlaybackPanel);
            app.AudioOutputDeviceDropDown.Items = {'None'};
            app.AudioOutputDeviceDropDown.ValueChangedFcn = createCallbackFcn(app, @AudioOutputDeviceDropDownValueChanged, true);
            app.AudioOutputDeviceDropDown.Value = 'None';
            app.PlayButton = uibutton(app.AudioPlaybackPanel, 'state');
            app.PlayButton.ValueChangedFcn = createCallbackFcn(app, @PlayButtonValueChanged, true);
            app.PlayButton.Text = 'Play';
            app.PlaybackSpeedSpinnerLabel = uilabel(app.AudioPlaybackPanel);
            app.PlaybackSpeedSpinnerLabel.Text = 'Playback Speed ';
            app.PlaybackSpeedSpinner = uispinner(app.AudioPlaybackPanel);
            app.PlaybackSpeedSpinner.Limits = [1 10];
            app.PlaybackSpeedSpinner.RoundFractionalValues = 'on';
            app.PlaybackSpeedSpinner.Value = 1;
            app.XLabel = uilabel(app.AudioPlaybackPanel);
            app.XLabel.Text = 'X';
            app.LoopPlaySwitchLabel = uilabel(app.AudioPlaybackPanel);
            app.LoopPlaySwitchLabel.Text = 'Loop Play';
            app.LoopPlaySwitch = uiswitch(app.AudioPlaybackPanel, 'slider');
            app.VolumeSliderLabel = uilabel(app.AudioPlaybackPanel);
            app.VolumeSliderLabel.Text = 'Volume';
            app.VolumeSlider = uislider(app.AudioPlaybackPanel);
            app.VolumeSlider.ValueChangedFcn = createCallbackFcn(app, @VolumeSliderValueChanged, true);
            app.VolumeSlider.Value = 100;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = BASSA

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end