classdef mex_test_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        SLTResultTestPanel            matlab.ui.container.Panel
        RMSEBetweenNativeMexResultsEditField  matlab.ui.control.NumericEditField
        RMSEBetweenNativeMexResultsEditFieldLabel  matlab.ui.control.Label
        TestStatusPanel               matlab.ui.container.Panel
        GraphicsRenderingLamp         matlab.ui.control.Lamp
        GraphicsRenderingLampLabel    matlab.ui.control.Label
        SuperletTransformLamp         matlab.ui.control.Lamp
        SuperletTransformLabel        matlab.ui.control.Label
        TestSystemLamp                matlab.ui.control.Lamp
        TestSystemLampLabel           matlab.ui.control.Label
        RedWaitingYellowInProgressGreenDoneLabel  matlab.ui.control.Label
        RUNTESTButton                 matlab.ui.control.Button
        TestSetupPanel                matlab.ui.container.Panel
        RAM_est_EditField             matlab.ui.control.NumericEditField
        RAM_est_EditFieldLabel        matlab.ui.control.Label
        SLTFrequencyResolutionHzEditField  matlab.ui.control.NumericEditField
        SLTFrequencyResolutionHzEditFieldLabel  matlab.ui.control.Label
        SLTUpperFrequencyLimitHzEditField  matlab.ui.control.NumericEditField
        SLTUpperFrequencyLimitHzEditFieldLabel  matlab.ui.control.Label
        SLTLowerFrequencyLimitHzEditField  matlab.ui.control.NumericEditField
        SLTLowerFrequencyLimitHzEditFieldLabel  matlab.ui.control.Label
        TestSignalSampleRateHzEditField  matlab.ui.control.NumericEditField
        TestSignalSampleRateHzEditFieldLabel  matlab.ui.control.Label
        TestSignalDurationsEditField  matlab.ui.control.NumericEditField
        TestSignalDurationsEditFieldLabel  matlab.ui.control.Label
        INITTESTButton                matlab.ui.control.Button
        ProfilingPanel                matlab.ui.container.Panel
        pcolorSLTTotalTimesEditField  matlab.ui.control.NumericEditField
        pcolorSLTTotalTimesEditFieldLabel  matlab.ui.control.Label
        TotalTimeMEXsEditField        matlab.ui.control.NumericEditField
        TotalTimeMEXsEditFieldLabel   matlab.ui.control.Label
        TotaltimeMatlabNativesEditField  matlab.ui.control.NumericEditField
        TotaltimeMatlabNativesEditFieldLabel  matlab.ui.control.Label
        pcolorplotdrawingtimeisforonefigurenotbothLabel  matlab.ui.control.Label
        UIAxes_SLT_MEX                matlab.ui.control.UIAxes
        UIAxes_SLT_NATIVE             matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        testsig                 % The signal to transform
        duration                % The duration of the test signal
        fs                      % Sampling Frequency
        t_vec                   % Time vector for the final test signal
        f_vec                   % Frequency vector for the SLT result
        Fi                      % Frequency Interval of SLT
        Nf                      % Number of frequency points in SLT
        fres                    % Frequency Resolution of SLT
        c1                      % Number of Cycles in initial SLT
        o                       % Interval of SLT orders
        mult                    % Additive or Multiplicitive superresolution
        
    end
    
    methods (Access = private)
        % Estimate Memory Required
        function mem_est(app)
            [mem_required, units] = SLTmemorytest(app.testsig, app.fs,...
                app.Fi,app.Nf, app.c1, app.o, app.mult);
            app.RAM_est_EditFieldLabel.Text = ['Estimated RAM To Compute ', '(', units, ')'];
            app.RAM_est_EditField.Value = mem_required;
        end

        % Synthesise test signal
        function gen_test_sig(app, f1, f2, amf1, amf2)
            samps = app.duration * app.fs;
            app.t_vec = linspace(1, app.duration, samps);
            sweep = rescale(chirp(app.t_vec, f1, app.t_vec(end), f2), -1, 1);
            modulator = rescale(sign(chirp(app.t_vec, amf1, app.t_vec(end),...
                amf2, [], -90)), 0, 1);
            noisy = rescale(rand(1, samps), -0.1, 0.1);
            app.testsig = noisy + (sweep .* modulator);
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Initialise App

            % Set running lamp to not running (red).
            app.TestSystemLamp.Color = "red";
            drawnow;
            figure(app.UIFigure)

            % Set superlet transform lamp to not running (red).
            app.SuperletTransformLamp.Color = "red";
            drawnow;
            figure(app.UIFigure)

            % Set Rendering lamp to not running (red).
            app.GraphicsRenderingLamp.Color = "red";
            drawnow;
            figure(app.UIFigure)
            
            % Init Test signal parameters
            app.duration = 200; % seconds
            app.TestSignalDurationsEditField.Value = app.duration; % seconds
            app.fs = 250; % Hz
            app.TestSignalSampleRateHzEditField.Value = app.fs; % Hz
            f1 = 30; % Hz
            f2 = 80; % Hz
            amf1 = 0.5; % Hz
            amf2 = 5; % Hz

            % Init Superlet Parameters
            app.Fi = [1, (app.fs/2)-1];
            app.SLTLowerFrequencyLimitHzEditField.Value = app.Fi(1);
            app.SLTUpperFrequencyLimitHzEditField.Value = app.Fi(2);
            app.fres = 0.5;
            app.SLTFrequencyResolutionHzEditField.Value = app.fres;
            app.c1 = 4;
            app.o = [10, 40];
            app.mult = 1;
            app.Nf = range(app.Fi)/app.fres;
            app.f_vec = linspace(app.Fi(1), app.Fi(2), app.Nf);

            % Generate test signal
            gen_test_sig(app, f1, f2, amf1, amf2)
            
            % Run Memory est
            mem_est(app)

        end

        % Button pushed function: RUNTESTButton
        function RUNTESTButtonPushed(app, event)
            % Set running lamp to "in progress" (yellow).
            app.TestSystemLamp.Color = "yellow";
            drawnow;
            figure(app.UIFigure)

            % Set Superlet Transform lamp to In Progress (Yellow).
            app.SuperletTransformLamp.Color = "yellow";
            drawnow;
            figure(app.UIFigure)

%             % Begin Profiling
%             profile on

            % Run native implementaion of faslt analysis
            SLT = nfaslt(app.testsig, app.fs, app.Fi, app.Nf, app.c1, app.o, app.mult);

            % Run MEX implementation of faslt analysis
            SLT_mex = faslt_mex(app.testsig, app.fs, app.Fi, app.Nf, app.c1, app.o, app.mult);

            % Set SLT Computed lamp to DONE (green).
            app.SuperletTransformLamp.Color = "green";
            drawnow;
            figure(app.UIFigure)

            % Normalize Magnitudes of Results
            SLT = SLT ./ max(abs(SLT));
            SLT_mex = SLT_mex ./ max(abs(SLT_mex));
           
            % Set Rendering lamp to IN Progress (yellow).
            app.GraphicsRenderingLamp.Color = "yellow";
            drawnow;
            figure(app.UIFigure)
            
            % Plot results
            s1 = pcolor(app.UIAxes_SLT_NATIVE, app.t_vec, app.f_vec, SLT);
            s1.EdgeColor = 'none';

%             s2 = pcolor(app.UIAxes_SLT_MEX, app.t_vec, app.f_vec, SLT_mex);
%             s2.EdgeColor = 'none';
            drawnow;
            figure(app.UIFigure)

            % Set Rendering lamp to DONE (green).
            app.GraphicsRenderingLamp.Color = "green";
            drawnow;
            figure(app.UIFigure)

%             % Finish Profiling
%             p = profile('info');

%             % Extract profiling results
%             native_linenum = find(strcmp({p.FunctionTable.FunctionName}, 'nfaslt'));
%             mex_linenum = find(strcmp({p.FunctionTable.FunctionName}, 'faslt_mex'));
%             pcolor_linenum = find(strcmp({p.FunctionTable.FunctionName}, 'pcolor'));
%             app.TotaltimeMatlabNativesEditField.Value = p.FunctionTable(native_linenum).TotalTime;
%             app.TotalTimeMEXsEditField.Value = p.FunctionTable(mex_linenum).TotalTime;
%             app.pcolorSLTTotalTimesEditField.Value = p.FunctionTable(pcolor_linenum).TotalTime /2;
            
            % Check Error & Display on UI field
            app.RMSEBetweenNativeMexResultsEditField.Value = rmse(SLT, SLT_mex, 'all');

            % Set running lamp to "DONE" (green).
            app.TestSystemLamp.Color = "green";
            drawnow;
            figure(app.UIFigure)

        end

        % Button pushed function: INITTESTButton
        function INITTESTButtonPushed(app, event)
            % rebuild the test signal.
            % Set new Test signal parameters
            app.duration = app.TestSignalDurationsEditField.Value; % seconds
            app.fs = app.TestSignalSampleRateHzEditField.Value; % Hz
            
            % Re-Initialise Superlet Parameters
            app.Fi = [app.SLTLowerFrequencyLimitHzEditField.Value, ...
                app.SLTUpperFrequencyLimitHzEditField.Value];
            app.fres = app.SLTFrequencyResolutionHzEditField.Value;
            app.Nf = range(app.Fi)/app.fres;
            app.f_vec = linspace(app.Fi(1), app.Fi(2), app.Nf);

            % Re-Generate test signal
            f1 = 30; % Hz
            f2 = 80; % Hz
            amf1 = 0.5; % Hz
            amf2 = 5; % Hz
            gen_test_sig(app, f1, f2, amf1, amf2)
            
            % Re-Run Memory est
            mem_est(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes_SLT_NATIVE = uiaxes(app.UIFigure);
            title(app.UIAxes_SLT_NATIVE, 'Matlab Native "nfaslt.m"')
            xlabel(app.UIAxes_SLT_NATIVE, 'X')
            ylabel(app.UIAxes_SLT_NATIVE, 'Y')
            zlabel(app.UIAxes_SLT_NATIVE, 'Z')
            app.UIAxes_SLT_MEX = uiaxes(app.UIFigure);
            title(app.UIAxes_SLT_MEX, 'Mex Implementation "faslt_mex.mexw64"')
            xlabel(app.UIAxes_SLT_MEX, 'X')
            ylabel(app.UIAxes_SLT_MEX, 'Y')
            zlabel(app.UIAxes_SLT_MEX, 'Z')
            app.ProfilingPanel = uipanel(app.UIFigure);
            app.ProfilingPanel.Title = 'Profiling';
            app.pcolorplotdrawingtimeisforonefigurenotbothLabel = uilabel(app.ProfilingPanel);
            app.pcolorplotdrawingtimeisforonefigurenotbothLabel.Text = 'pcolor plot drawing time is for one figure, not both';
            app.TotaltimeMatlabNativesEditFieldLabel = uilabel(app.ProfilingPanel);
            app.TotaltimeMatlabNativesEditFieldLabel.Text = 'Total time (Matlab Native, s) ';
            app.TotaltimeMatlabNativesEditField = uieditfield(app.ProfilingPanel, 'numeric');
            app.TotalTimeMEXsEditFieldLabel = uilabel(app.ProfilingPanel);
            app.TotalTimeMEXsEditFieldLabel.Text = 'Total Time (MEX, s)';
            app.TotalTimeMEXsEditField = uieditfield(app.ProfilingPanel, 'numeric');
            app.pcolorSLTTotalTimesEditFieldLabel = uilabel(app.ProfilingPanel);
            app.pcolorSLTTotalTimesEditFieldLabel.Text = 'pcolor(SLT) Total Time (s)';
            app.pcolorSLTTotalTimesEditField = uieditfield(app.ProfilingPanel, 'numeric');
            app.TestSetupPanel = uipanel(app.UIFigure);
            app.TestSetupPanel.Title = 'Test Setup';
            app.INITTESTButton = uibutton(app.TestSetupPanel, 'push');
            app.INITTESTButton.ButtonPushedFcn = createCallbackFcn(app, @INITTESTButtonPushed, true);
            app.INITTESTButton.Text = 'INIT TEST';
            app.TestSignalDurationsEditFieldLabel = uilabel(app.TestSetupPanel);
            app.TestSignalDurationsEditFieldLabel.Text = 'Test Signal Duration (s)';
            app.TestSignalDurationsEditField = uieditfield(app.TestSetupPanel, 'numeric');
            app.TestSignalSampleRateHzEditFieldLabel = uilabel(app.TestSetupPanel);
            app.TestSignalSampleRateHzEditFieldLabel.Text = 'Test Signal Sample Rate (Hz)';
            app.TestSignalSampleRateHzEditField = uieditfield(app.TestSetupPanel, 'numeric');
            app.SLTLowerFrequencyLimitHzEditFieldLabel = uilabel(app.TestSetupPanel);
            app.SLTLowerFrequencyLimitHzEditFieldLabel.Text = 'SLT Lower Frequency Limit (Hz) ';
            app.SLTLowerFrequencyLimitHzEditField = uieditfield(app.TestSetupPanel, 'numeric');
            app.SLTUpperFrequencyLimitHzEditFieldLabel = uilabel(app.TestSetupPanel);
            app.SLTUpperFrequencyLimitHzEditFieldLabel.Text = 'SLT Upper Frequency Limit (Hz) ';
            app.SLTUpperFrequencyLimitHzEditField = uieditfield(app.TestSetupPanel, 'numeric');
            app.SLTFrequencyResolutionHzEditFieldLabel = uilabel(app.TestSetupPanel);
            app.SLTFrequencyResolutionHzEditFieldLabel.Text = 'SLT Frequency Resolution (Hz)';
            app.SLTFrequencyResolutionHzEditField = uieditfield(app.TestSetupPanel, 'numeric');
            app.RAM_est_EditFieldLabel = uilabel(app.TestSetupPanel);
            app.RAM_est_EditFieldLabel.Text = 'Estimated RAM To Compute (units)';
            app.RAM_est_EditField = uieditfield(app.TestSetupPanel, 'numeric');
            app.RAM_est_EditField.Editable = 'off';
            app.TestStatusPanel = uipanel(app.UIFigure);
            app.TestStatusPanel.Title = 'Test Status';
            app.RUNTESTButton = uibutton(app.TestStatusPanel, 'push');
            app.RUNTESTButton.ButtonPushedFcn = createCallbackFcn(app, @RUNTESTButtonPushed, true);
            app.RUNTESTButton.Text = 'RUN TEST';
            app.RedWaitingYellowInProgressGreenDoneLabel = uilabel(app.TestStatusPanel);
            app.RedWaitingYellowInProgressGreenDoneLabel.Text = 'Red: Waiting     Yellow: In Progress     Green: Done';
            app.TestSystemLampLabel = uilabel(app.TestStatusPanel);
            app.TestSystemLampLabel.Text = 'Test System';
            app.TestSystemLamp = uilamp(app.TestStatusPanel);
            app.SuperletTransformLabel = uilabel(app.TestStatusPanel);
            app.SuperletTransformLabel.Text = 'Superlet Transform';
            app.SuperletTransformLamp = uilamp(app.TestStatusPanel);
            app.GraphicsRenderingLampLabel = uilabel(app.TestStatusPanel);
            app.GraphicsRenderingLampLabel.Text = 'Graphics Rendering';
            app.GraphicsRenderingLamp = uilamp(app.TestStatusPanel);
            app.SLTResultTestPanel = uipanel(app.UIFigure);
            app.SLTResultTestPanel.Title = 'SLT Result Test';
            app.RMSEBetweenNativeMexResultsEditFieldLabel = uilabel(app.SLTResultTestPanel);
            app.RMSEBetweenNativeMexResultsEditFieldLabel.Text = 'RMSE Between Native & Mex Results';
            app.RMSEBetweenNativeMexResultsEditField = uieditfield(app.SLTResultTestPanel, 'numeric');
            app.RMSEBetweenNativeMexResultsEditField.Editable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = mex_test_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end